classdef stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ISI                          matlab.ui.control.Spinner
        InterStimulusIntervalmsecSpinnerLabel  matlab.ui.control.Label
        EastSpeakerPanel             matlab.ui.container.Panel
        East_plot_type               matlab.ui.control.RockerSwitch
        West_modulator_plot          matlab.ui.control.UIAxes
        East_stimulus_plot           matlab.ui.control.UIAxes
        East_carrier_plot            matlab.ui.control.UIAxes
        WestSpeakerPanel             matlab.ui.container.Panel
        West_plot_type               matlab.ui.control.RockerSwitch
        West_stimulus_plot           matlab.ui.control.UIAxes
        West_carrier_plot            matlab.ui.control.UIAxes
        StatusPanel                  matlab.ui.container.Panel
        TransmissionLamp             matlab.ui.control.Lamp
        TransmissionLampLabel        matlab.ui.control.Label
        DataTransferLamp             matlab.ui.control.Lamp
        DataTransferLampLabel        matlab.ui.control.Label
        ConnectionLamp               matlab.ui.control.Lamp
        ConnectionLampLabel          matlab.ui.control.Label
        ControlSwitch                matlab.ui.control.ToggleSwitch
        Speaker                      matlab.ui.control.DiscreteKnob
        TabGroup                     matlab.ui.container.TabGroup
        SearchTab                    matlab.ui.container.Tab
        ControlPanel                 matlab.ui.container.Panel
        FrequencyCyclerHzPanel       matlab.ui.container.Panel
        End_frequency                matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        Step_frequency               matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        Start_frequency              matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        SpeciesDropDown              matlab.ui.control.DropDown
        SpeciesDropDownLabel         matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        ModulatorFrequenciesHzPanel  matlab.ui.container.Panel
        Fm_check_5                   matlab.ui.control.CheckBox
        Fm_5                         matlab.ui.control.NumericEditField
        CarrierFrequenciesHzPanel    matlab.ui.container.Panel
        Fm_check_4                   matlab.ui.control.CheckBox
        Fm_4                         matlab.ui.control.NumericEditField
        Fm_check_3                   matlab.ui.control.CheckBox
        Fm_3                         matlab.ui.control.NumericEditField
        Fm_check_2                   matlab.ui.control.CheckBox
        Fm_2                         matlab.ui.control.NumericEditField
        Fm_1                         matlab.ui.control.NumericEditField
        Fm_check_1                   matlab.ui.control.CheckBox
        Fc_check_5                   matlab.ui.control.CheckBox
        Fc_5                         matlab.ui.control.NumericEditField
        Fc_check_4                   matlab.ui.control.CheckBox
        Fc_4                         matlab.ui.control.NumericEditField
        Fc_check_3                   matlab.ui.control.CheckBox
        Fc_3                         matlab.ui.control.NumericEditField
        Fc_check_2                   matlab.ui.control.CheckBox
        Fc_2                         matlab.ui.control.NumericEditField
        Fc_1                         matlab.ui.control.NumericEditField
        Fc_check_1                   matlab.ui.control.CheckBox
        East_modulator_plot          matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        RPcontroller % actxcontrol of RP2.1 processor
    end
    
    methods (Access = private)
        
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            disp(app.RPcontroller.ConnectRP2('GB', 1));
        end
        
        %% Search Tab Functions
        function search_mode_normal(app)
            % Search Tab normal mode configuration.
            app.Fc_check_1.Value = 1;
            app.Fc_check_2.Value = 1;
            app.Fc_check_3.Value = 1;
            app.Fc_check_4.Value = 1;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 1;
            app.Fm_check_3.Value = 1;
            app.Fm_check_4.Value = 1;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 0;
            app.Step_frequency.Enable = 0;
            app.End_frequency.Enable = 0;
        end
        
        function search_mode_fixed_carrier(app)
            % Fixed carrier configuration.
            app.Fc_check_1.Value = 1;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 0;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 0;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 10;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 10;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 80;
        end
        
        function search_mode_fixed_modulation(app)
            % Fixed modulation configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 0;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 0;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 300;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 20;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 600;
        end
        
        function search_mode_basilar_papilla(app)
            % Basilar Papilla Configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 1;
            app.Fm_check_3.Value = 1;
            app.Fm_check_4.Value = 1;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 10;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 10;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 80;
        end
        
        function freqs = search_get_carriers(app)
            % Collect active carrier frequencies.
            freqs = [];
            if app.Fc_check_1.Value == 1
                freqs(end+1) = app.Fc_1.Value;
            end
            if app.Fc_check_2.Value == 1
                freqs(end+1) = app.Fc_2.Value;
            end
            if app.Fc_check_3.Value == 1
                freqs(end+1) = app.Fc_3.Value;
            end
            if app.Fc_check_4.Value == 1
                freqs(end+1) = app.Fc_4.Value;
            end
            if app.Fc_check_5.Value == 1
                freqs(end+1) = app.Fc_5.Value;
            end
        end
        
        function freqs = search_get_modulators(app)
            % Collect modulation frequencies.
            freqs = [];
            if app.Fm_check_1.Value == 1
                freqs(end+1) = app.Fm_1.Value;
            end
            if app.Fm_check_2.Value == 1
                freqs(end+1) = app.Fm_2.Value;
            end
            if app.Fm_check_3.Value == 1
                freqs(end+1) = app.Fm_3.Value;
            end
            if app.Fm_check_4.Value == 1
                freqs(end+1) = app.Fm_4.Value;
            end
            if app.Fm_check_5.Value == 1
                freqs(end+1) = app.Fm_5.Value;
            end
        end
        
        function [time, carriers, modulators, stimuli] = search_build_stimulus(app, carrier_freq_list, modulator_freq_list)
            sampling_rate = 24414.0625; % Sampling Rate of TDT System III RP2.1. Units: Hz.
            stimulus_duration = app.ISI.Value*1e-3; % Inter-stimulus interval. Converted from msec to sec.
            time = 0: (1/sampling_rate) : (stimulus_duration - (1/sampling_rate)); % Array of time samples.
            carriers = sin(2*pi*carrier_freq_list'.*time); % Generate carrier sinusoid for all carrier frequencies.
            modulators = sin(2*pi*modulator_freq_list'.*time); % Generate modulator sinusoid for all modulator frequencies.
            stimuli = carrier .* modulator; % Generate stimuli.
        end
        
        function signal_fft = compute_fft(~, signal, time)
            samples = size(time, 2);
            n = 2^nextpow2(samples);
            signal_fft = fft(signal, n, 2);
            signal_fft = abs(signal_fft/samples);
            signal_fft = signal_fft(:, 1:n/2+1);
            signal_fft(:, 2:end-1) = 2*signal_fft(:, 2:end-1);
        end
        
        function [carriers_freq, carriers_fft, modulators_freq, modulators_fft, stimuli_fft] = get_fft(app, time, carriers, modulators, stimuli, max_fc, max_fm)
            sampling_rate = 24144.0625; % TDT System 3 sampling rate.
            carriers_fft = app.compute_fft(carriers, time);
            modulators_fft = app.compute_fft(modulators, time);
            stimuli_fft = app.compute_fft(stimuli, time);
            freq = 0:(sampling_rate/n):(sampling_rate/2 - sampling_rate/n);
            carriers_freq = freq(freq <= (max_fc+200));
            modulators_freq = freq(freq <= (max_fm+20));
            carriers_fft = carriers_fft(:, 1:size(carrier_freq, 2));
            modulators_fft = modulators_fft(:, 1:size(modulator_freq, 2));
            stimuli_fft = stimuli_fft(:, 1:size(carrier_freq, 2));
        end
        
        function search_plot_stimuli(app, time, carriers, modulators, stimuli)
            value = app.Speaker.Value;
            if value == "East"
                app.plot_east_speaker(time, carriers, modulators, stimuli);
            elseif value == "West"
                app.plot_west_speaker(time, carriers, modulators, stimuli);
            end
        end
        
        function plot_east_speaker(app, time, carriers, modulators, stimuli)
            plot_type = app.East_plot_type;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carriers, 'LineWidth', 0.5, "XData", time);
                plot(app.East_modulator_plot, modulators, 'LineWidth', 0.5, "XData", time);
                plot(app.East_stimulus_plot, stimuli, 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carriers_freq, carriers_fft, modulators_freq, modulators_fft, stimuli_fft] = app.get_fft(time, carriers, modulators, stimuli, max_fc, max_fm);
                plot(app.East_carrier_plot, carriers_fft, 'LineWidth', 0.5, "XData", carriers_freq);
                plot(app.East_modulator_plot, modulators_fft, 'LineWidth', 0.5, "XData", modulators_freq);
                plot(app.East_stimulus_plot, stimuli_fft, 'LineWidth', 0.5, "XData", carriers_freq);
            end
        end
        
        function plot_west_speaker(app, time, carriers, modulators, stimuli)
            plot_type = app.West_plot_type;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carriers, 'LineWidth', 0.5, "XData", time);
                plot(app.West_modulator_plot, modulators, 'LineWidth', 0.5, "XData", time);
                plot(app.West_stimulus_plot, stimuli, 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carriers_freq, carriers_fft, modulators_freq, modulators_fft, stimuli_fft] = app.get_fft(time, carriers, modulators, stimuli, max_fc, max_fm);
                plot(app.West_carrier_plot, carriers_fft, 'LineWidth', 0.5, "XData", carriers_freq);
                plot(app.West_modulator_plot, modulators_fft, 'LineWidth', 0.5, "XData", modulators_freq);
                plot(app.West_stimulus_plot, stimuli_fft, 'LineWidth', 0.5, "XData", carriers_freq);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            value = app.SpeciesDropDown.Value;
            if value == "Rana pippiens"
                app.Fc_5.Value = 1600;
            elseif value == "Hyla versicolor"
                app.Fc_5.Value = 2200;
            elseif value == "Hyla chrysocelis"
                app.Fc_5.Value = 2200;
            elseif value == "Pseudacris feriarum"
                app.Fc_5.Value = 3100;
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if value == "Normal"
                app.search_mode_normal();
            elseif value == "Fixed Carrier"
                app.search_mode_fixed_carrier();
            elseif value == "Fixed Modulation"
                app.search_mode_fixed_modulation();
            elseif value == "Basilar Papilla"
                app.search_mode_basilar_papilla();
            end
        end

        % Value changed function: Fc_check_1
        function Fc_check_1ValueChanged(app, event)
            value = app.Fc_check_1.Value;
            if value == 0
                app.Fc_1.Enable = 0;
            else
                app.Fc_1.Enable = 1;
            end
        end

        % Value changed function: Fc_check_2
        function Fc_check_2ValueChanged(app, event)
            value = app.Fc_check_2.Value;
            if value == 0
                app.Fc_2.Enable = 0;
            else
                app.Fc_2.Enable = 1;
            end
        end

        % Value changed function: Fc_check_3
        function Fc_check_3ValueChanged(app, event)
            value = app.Fc_check_3.Value;
            if value == 0
                app.Fc_3.Enable = 0;
            else
                app.Fc_3.Enable = 1;
            end
        end

        % Value changed function: Fc_check_4
        function Fc_check_4ValueChanged(app, event)
            value = app.Fc_check_4.Value;
            if value == 0
                app.Fc_4.Enable = 0;
            else
                app.Fc_4.Enable = 1;
            end
        end

        % Value changed function: Fc_check_5
        function Fc_check_5ValueChanged(app, event)
            value = app.Fc_check_5.Value;
            if value == 0
                app.Fc_5.Enable = 0;
            else
                app.Fc_5.Enable = 1;
            end
        end

        % Value changed function: Fm_check_1
        function Fm_check_1ValueChanged(app, event)
            value = app.Fm_check_1.Value;
            if value == 0
                app.Fm_1.Enable = 0;
            else
                app.Fm_1.Enable = 1;
            end
        end

        % Value changed function: Fm_check_2
        function Fm_check_2ValueChanged(app, event)
            value = app.Fm_check_2.Value;
            if value == 0
                app.Fm_2.Enable = 0;
            else
                app.Fm_2.Enable = 1;
            end
        end

        % Value changed function: Fm_check_3
        function Fm_check_3ValueChanged(app, event)
            value = app.Fm_check_3.Value;
            if value == 0
                app.Fm_3.Enable = 0;
            else
                app.Fm_3.Enable = 1;
            end
        end

        % Value changed function: Fm_check_4
        function Fm_check_4ValueChanged(app, event)
            value = app.Fm_check_4.Value;
            if value == 0
                app.Fm_4.Enable = 0;
            else
                app.Fm_4.Enable = 1;
            end
        end

        % Value changed function: Fm_check_5
        function Fm_check_5ValueChanged(app, event)
            value = app.Fm_check_5.Value;
            if value == 0
                app.Fm_5.Enable = 0;
            else
                app.Fm_5.Enable = 1;
            end
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            value = app.ControlSwitch.Value;
            if value == "On"
                app.hardware_initialization();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.ControlPanel = uipanel(app.SearchTab);
            app.ControlPanel.Title = 'Control';
            app.CarrierFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.CarrierFrequenciesHzPanel.Title = 'Carrier Frequencies (Hz)';
            app.Fc_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_check_1ValueChanged, true);
            app.Fc_check_1.Text = '';
            app.Fc_check_1.Value = true;
            app.Fc_1 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_1.Value = 150;
            app.Fc_2 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_2.Value = 300;
            app.Fc_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_check_2ValueChanged, true);
            app.Fc_check_2.Text = '';
            app.Fc_check_2.Value = true;
            app.Fc_3 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_3.Value = 600;
            app.Fc_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_check_3ValueChanged, true);
            app.Fc_check_3.Text = '';
            app.Fc_check_3.Value = true;
            app.Fc_4 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_4.Value = 1100;
            app.Fc_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_check_4ValueChanged, true);
            app.Fc_check_4.Text = '';
            app.Fc_check_4.Value = true;
            app.Fc_5 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_5.Value = 1600;
            app.Fc_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_check_5ValueChanged, true);
            app.Fc_check_5.Text = '';
            app.Fc_check_5.Value = true;
            app.ModulatorFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.ModulatorFrequenciesHzPanel.Title = 'Modulator Frequencies (Hz)';
            app.Fm_check_1 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_check_1ValueChanged, true);
            app.Fm_check_1.Text = '';
            app.Fm_check_1.Value = true;
            app.Fm_1 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_1.Value = 10;
            app.Fm_2 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_2.Value = 20;
            app.Fm_check_2 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_check_2ValueChanged, true);
            app.Fm_check_2.Text = '';
            app.Fm_check_2.Value = true;
            app.Fm_3 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_3.Value = 40;
            app.Fm_check_3 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_check_3ValueChanged, true);
            app.Fm_check_3.Text = '';
            app.Fm_check_3.Value = true;
            app.Fm_4 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_4.Value = 60;
            app.Fm_check_4 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_check_4ValueChanged, true);
            app.Fm_check_4.Text = '';
            app.Fm_check_4.Value = true;
            app.Fm_5 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_5.Value = 80;
            app.Fm_check_5 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_check_5ValueChanged, true);
            app.Fm_check_5.Text = '';
            app.Fm_check_5.Value = true;
            app.ModeDropDownLabel = uilabel(app.ControlPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ControlPanel);
            app.ModeDropDown.Items = {'Normal', 'Fixed Carrier', 'Fixed Modulation', 'Basilar Papilla'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Normal';
            app.SpeciesDropDownLabel = uilabel(app.ControlPanel);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.ControlPanel);
            app.SpeciesDropDown.Items = {'Rana pippiens', 'Hyla versicolor', 'Hyla chrysocelis', 'Pseudacris feriarum'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Rana pippiens';
            app.FrequencyCyclerHzPanel = uipanel(app.ControlPanel);
            app.FrequencyCyclerHzPanel.Title = 'Frequency Cycler (Hz)';
            app.StartEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Start_frequency.Value = 300;
            app.StepEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.Step_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Step_frequency.Value = 20;
            app.EndEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.End_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.End_frequency.Value = 600;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Speaker = uiknob(app.UIFigure, 'discrete');
            app.Speaker.Items = {'East', 'Biaural', 'West'};
            app.Speaker.Value = 'West';
            app.ControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.ConnectionLampLabel = uilabel(app.StatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.StatusPanel);
            app.DataTransferLampLabel = uilabel(app.StatusPanel);
            app.DataTransferLampLabel.Text = 'Data Transfer';
            app.DataTransferLamp = uilamp(app.StatusPanel);
            app.TransmissionLampLabel = uilabel(app.StatusPanel);
            app.TransmissionLampLabel.Text = 'Transmission';
            app.TransmissionLamp = uilamp(app.StatusPanel);
            app.WestSpeakerPanel = uipanel(app.UIFigure);
            app.WestSpeakerPanel.Title = 'West Speaker';
            app.West_modulator_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_modulator_plot, 'Modulator')
            xlabel(app.West_modulator_plot, 'time (sec)')
            ylabel(app.West_modulator_plot, 'Amplitude (V)')
            zlabel(app.West_modulator_plot, 'Z')
            app.West_carrier_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_carrier_plot, 'Carrier')
            xlabel(app.West_carrier_plot, 'time (sec)')
            ylabel(app.West_carrier_plot, 'Amplitude (V)')
            zlabel(app.West_carrier_plot, 'Z')
            app.West_stimulus_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_stimulus_plot, 'Stimulus')
            xlabel(app.West_stimulus_plot, 'time (sec)')
            ylabel(app.West_stimulus_plot, 'Amplitude (V)')
            zlabel(app.West_stimulus_plot, 'Z')
            app.West_plot_type = uiswitch(app.WestSpeakerPanel, 'rocker');
            app.West_plot_type.Items = {'Time', 'FFT'};
            app.West_plot_type.Value = 'Time';
            app.EastSpeakerPanel = uipanel(app.UIFigure);
            app.EastSpeakerPanel.Title = 'East Speaker';
            app.East_modulator_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_modulator_plot, 'Modulator')
            xlabel(app.East_modulator_plot, 'time (sec)')
            ylabel(app.East_modulator_plot, 'Amplitude (V)')
            zlabel(app.East_modulator_plot, 'Z')
            app.East_carrier_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_carrier_plot, 'Carrier')
            xlabel(app.East_carrier_plot, 'time (sec)')
            ylabel(app.East_carrier_plot, 'Amplitude (V)')
            zlabel(app.East_carrier_plot, 'Z')
            app.East_stimulus_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_stimulus_plot, 'Stimulus')
            xlabel(app.East_stimulus_plot, 'time (sec)')
            ylabel(app.East_stimulus_plot, 'Amplitude (V)')
            zlabel(app.East_stimulus_plot, 'Z')
            app.East_plot_type = uiswitch(app.EastSpeakerPanel, 'rocker');
            app.East_plot_type.Items = {'Time', 'FFT'};
            app.East_plot_type.Value = 'Time';
            app.InterStimulusIntervalmsecSpinnerLabel = uilabel(app.UIFigure);
            app.InterStimulusIntervalmsecSpinnerLabel.Text = 'Inter-Stimulus Interval (msec)';
            app.ISI = uispinner(app.UIFigure);
            app.ISI.Step = 50;
            app.ISI.Value = 400;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end