classdef stimulusV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        Tab2                           matlab.ui.container.Tab
        SystemPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        SpeciesDropDown                matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        TDTStatusPanel                 matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        InterStimulusIntervalSlider    matlab.ui.control.Slider
        StimulusDurationmsecEditField  matlab.ui.control.NumericEditField
        StimulusDurationmsecEditFieldLabel  matlab.ui.control.Label
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        InterStimulusIntervalEditBox   matlab.ui.control.NumericEditField
        Panel_5                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        InterSpeakerIntervalSlider     matlab.ui.control.Slider
        SpeakerSelectKnob              matlab.ui.control.DiscreteKnob
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        InterSpeakerIntervalEditBox    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MasterState % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InterSpeakerIntervalEditBox.Limits = app.InterSpeakerIntervalSlider.Limits;
            app.InterStimulusIntervalEditBox.Limits = app.InterStimulusIntervalSlider.Limits;
            app.MasterState = 1;
        end

        % Value changed function: InterStimulusIntervalSlider
        function InterStimulusIntervalSliderValueChanged(app, event)
            app.InterStimulusIntervalEditBox.Value = app.InterStimulusIntervalSlider.Value;
        end

        % Value changed function: InterStimulusIntervalEditBox
        function InterStimulusIntervalEditBoxValueChanged(app, event)
            app.InterStimulusIntervalSlider.Value = app.InterStimulusIntervalEditBox.Value;
        end

        % Value changed function: InterSpeakerIntervalSlider
        function InterSpeakerIntervalSliderValueChanged(app, event)
            app.InterSpeakerIntervalEditBox.Value = app.InterSpeakerIntervalSlider.Value;
        end

        % Value changed function: InterSpeakerIntervalEditBox
        function InterSpeakerIntervalEditBoxValueChanged(app, event)
            app.InterSpeakerIntervalSlider.Value = app.InterSpeakerIntervalEditBox.Value;
        end

        % Button pushed function: MasterSwitch
        function MasterSwitchPushed(app, event)
            if app.MasterState == 1
                app.MasterState = 0;
                app.MasterSwitch.Icon = "./icons/mute.svg";
                app.MasterSwitch.BackgroundColor = [1.00,0.00,0.00];
            elseif app.MasterState == 0
                app.MasterState = 1;
                app.MasterSwitch.Icon = "./icons/unmute.svg";
                app.MasterSwitch.BackgroundColor = [0.00,1.00,0.00];
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemPanel = uipanel(app.GridLayout);
            app.SystemPanel.Title = 'System';
            app.GridLayout2 = uigridlayout(app.SystemPanel);
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_5);
            app.InterSpeakerIntervalEditBox = uieditfield(app.GridLayout4, 'numeric');
            app.InterSpeakerIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalEditBoxValueChanged, true);
            app.InterSpeakerIntervalEditBox.Value = 5;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.GridLayout4);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.SpeakerSelectKnob = uiknob(app.GridLayout4, 'discrete');
            app.SpeakerSelectKnob.Items = {'West', 'Biaural', 'East'};
            app.SpeakerSelectKnob.Value = 'West';
            app.Panel = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.InterSpeakerIntervalSlider = uislider(app.GridLayout5);
            app.InterSpeakerIntervalSlider.Limits = [-40 40];
            app.InterSpeakerIntervalSlider.MajorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.InterSpeakerIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalSliderValueChanged, true);
            app.InterSpeakerIntervalSlider.MinorTicks = [-40 -39 -38 -37 -36 -35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.InterSpeakerIntervalSlider.Value = 5;
            app.Panel_4 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.InterStimulusIntervalEditBox = uieditfield(app.GridLayout3, 'numeric');
            app.InterStimulusIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalEditBoxValueChanged, true);
            app.InterStimulusIntervalEditBox.Value = 1;
            app.InterStimulusIntervalsecLabel = uilabel(app.GridLayout3);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.StimulusDurationmsecEditFieldLabel = uilabel(app.GridLayout3);
            app.StimulusDurationmsecEditFieldLabel.Text = 'Stimulus Duration (msec)';
            app.StimulusDurationmsecEditField = uieditfield(app.GridLayout3, 'numeric');
            app.StimulusDurationmsecEditField.Value = 400;
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.InterStimulusIntervalSlider = uislider(app.GridLayout6);
            app.InterStimulusIntervalSlider.Limits = [0.5 6.5];
            app.InterStimulusIntervalSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5];
            app.InterStimulusIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalSliderValueChanged, true);
            app.InterStimulusIntervalSlider.MinorTicks = [];
            app.InterStimulusIntervalSlider.Value = 1;
            app.MasterSwitch = uibutton(app.GridLayout2, 'push');
            app.MasterSwitch.ButtonPushedFcn = createCallbackFcn(app, @MasterSwitchPushed, true);
            app.MasterSwitch.Icon = fullfile(pathToMLAPP, '.', 'icons', 'unmute.svg');
            app.MasterSwitch.Text = '';
            app.TDTStatusPanel = uipanel(app.GridLayout2);
            app.TDTStatusPanel.Title = 'TDT Status';
            app.GridLayout7 = uigridlayout(app.TDTStatusPanel);
            app.ConnectionLampLabel = uilabel(app.GridLayout7);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.GridLayout7);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.SpeciesDropDownLabel = uilabel(app.GridLayout8);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.GridLayout8);
            app.SpeciesDropDown.Items = {'Rana pipiens', 'Hyla chrysoceles', 'Hyla versicolor', 'Psudecris feriarum'};
            app.SpeciesDropDown.Value = 'Rana pipiens';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.GridLayout9 = uigridlayout(app.SearchTab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulusV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end